classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        thetaSliderLabel  matlab.ui.control.Label
        thetaSlider       matlab.ui.control.Slider
        Label             matlab.ui.control.Label
        YSliderLabel      matlab.ui.control.Label
        YSlider           matlab.ui.control.Slider
        XSliderLabel      matlab.ui.control.Label
        XSlider           matlab.ui.control.Slider
        WButton           matlab.ui.control.Button
        AButton           matlab.ui.control.Button
        DButton           matlab.ui.control.Button
        Label_2           matlab.ui.control.Label
        UpdateButton      matlab.ui.control.Button
        RNDButton         matlab.ui.control.Button
        RUNButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        crawler % Description
        map
        crawlerDists
        crawlerColls
        estimation
        renderer
        errors
        randomWalk
    end
    
    methods (Access = private)
        
        
        function update(app, dir)
            if nargin == 1
                dir = 'none'
            end          
            set(app.UpdateButton,'Enable','off')
            
            [app.crawlerDists, app.crawlerColls] = app.map.getUSCollision(app.crawler);
            
            estimatedCrawler = app.estimation.predictAndUpdate(dir, app.crawlerDists, 'particle');       
            [~, estimationColls] = app.map.getUSCollision(estimatedCrawler);
            
            likelihoodest = app.estimation.update.likelihoodXYTheta(app.crawlerDists, [estimatedCrawler.pos, estimatedCrawler.rot]);
            likelihoodreal = app.estimation.update.likelihoodXYTheta(app.crawlerDists, [app.crawler.pos, app.crawler.rot]);
            
            posError = norm(app.crawler.pos(1:2) - estimatedCrawler.pos(1:2));
            app.errors = [app.errors; posError];

            data = containers.Map;
            data('map') = app.map;
            data('crawler') = app.crawler;
            data('crawlerColls') = app.crawlerColls;
            data('estimation') = estimatedCrawler;
            data('estimationColls') = estimationColls;
            data('particles') = app.estimation.particles;
            data('errors') = app.errors;
            app.renderer.render(data);


            app.Label_2.Text = "Distance Error:" + num2str(posError,'%.4f') + newline + ...
                                "Likelihood est: " +  num2str(likelihoodest,'%.9f') + newline + ...
                                "Likelihood real: " +  num2str(likelihoodreal,'%.9f');
            app.Label.Text = "Real:" + num2str(app.crawler.rot,'%.4f') + newline + ...
                             "Estimated: " + mat2str(estimatedCrawler.pos, 3) + newline + ...
                             "Rot: " + num2str(estimatedCrawler.rot,'%.4f');

             app.estimation.update.plotLikelihood(app.crawlerDists, app.crawler.rot);
%             PlotSE2BinghamDist(app.estimation.filter.getEstimate(), app.UIAxes);
            set(app.UpdateButton,'Enable','on')
        end


        function estimation=estimatePosition(app)
            %opts = optimset('MaxIter',1000,'TolX',1e-6);
            [estimation,error, exitflag] =fminsearch(@app.getPositionError, [app.estimation.pos, app.estimation.rot]);
            app.Label.Text = "Exitflag: " + mat2str(exitflag) + newline +  "Optimierungs-Fehler: " + num2str(error,'%.4f');
            estimation = SimCrawler(estimation, app.map);
        end
        
        function error=getPositionError(app, crawler)
            [dists, ~] = app.map.getUSCollision(SimCrawler(crawler, app.map));
            error = norm(dists - app.crawlerDists);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            rng(2234);
            app.map = Map.Triangle();
            
            app.crawler = SimCrawler([26, -5, 0], app.map);
            app.crawler = SimCrawler([-10, 0, 0], app.map);
            app.crawler.noisetype = 'se2';
            app.estimation = Estimation(SimCrawler([10, 0, 0.5], app.map));
            app.randomWalk = RandomWalk(app.crawler, 'data complete 59', [100,-100]);
            
            app.renderer = Renderer(app.UIAxes);
            app.update();
            app.renderer.fixAxes();
            
        end

        % Value changing function: thetaSlider
        function thetaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.crawler.rot = changingValue;
            app.update();
        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            changingValue = event.Value;
            app.crawler.pos(1) = changingValue;
            app.update();
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            changingValue = event.Value;
            app.crawler.pos(2) = changingValue;
            app.update();
        end

        % Button pushed function: WButton
        function WButtonPushed(app, event)
            app.crawler.move('fwdStep');
            app.update('fwdStep');
        end

        % Button pushed function: AButton
        function AButtonPushed(app, event)
            app.crawler.move('turnl');
            app.update('turnl');
        end

        % Button pushed function: DButton
        function DButtonPushed(app, event)
            app.crawler.move('turnr');
            app.update('turnr');
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.update();
        end

        % Button pushed function: RNDButton
        function RNDButtonPushed(app, event)
            app.update(app.randomWalk.Step());
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            for i = 1:10
                app.update(app.randomWalk.Step());
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.thetaSliderLabel = uilabel(app.UIFigure);
            app.thetaSliderLabel.Text = 'theta';
            app.thetaSlider = uislider(app.UIFigure);
            app.thetaSlider.Limits = [0 6.5];
            app.thetaSlider.ValueChangingFcn = createCallbackFcn(app, @thetaSliderValueChanging, true);
            app.Label = uilabel(app.UIFigure);
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [0 1];
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.XSliderLabel = uilabel(app.UIFigure);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [0 1];
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.WButton = uibutton(app.UIFigure, 'push');
            app.WButton.ButtonPushedFcn = createCallbackFcn(app, @WButtonPushed, true);
            app.WButton.Text = 'W';
            app.AButton = uibutton(app.UIFigure, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.AButton.Text = 'A';
            app.DButton = uibutton(app.UIFigure, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.DButton.Text = 'D';
            app.Label_2 = uilabel(app.UIFigure);
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.RNDButton = uibutton(app.UIFigure, 'push');
            app.RNDButton.ButtonPushedFcn = createCallbackFcn(app, @RNDButtonPushed, true);
            app.RNDButton.Text = 'RND';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end